classdef DOT_Simulating_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OrenSchorRonMilutinLabel  matlab.ui.control.Label
        ScaleZEditField           matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel      matlab.ui.control.Label
        ScaleYEditField           matlab.ui.control.NumericEditField
        ScaleYEditFieldLabel      matlab.ui.control.Label
        ScaleXEditField           matlab.ui.control.NumericEditField
        ScaleXEditFieldLabel      matlab.ui.control.Label
        refEditField              matlab.ui.control.NumericEditField
        refEditFieldLabel         matlab.ui.control.Label
        mus1cmEditField           matlab.ui.control.NumericEditField
        mus1cmEditFieldLabel      matlab.ui.control.Label
        mua1cmEditField           matlab.ui.control.NumericEditField
        mua1cmEditFieldLabel      matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        StretchmeshCheckBox       matlab.ui.control.CheckBox
        DOTSimulatingToolLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            fullpath = mfilename('fullpath');
            fullpath = fileparts(fullpath);
            simdir = fullfile(fullpath,'Simulations');
            current_datetime = now;
            formattedDateTime = datestr(current_datetime, 'yyyy-mm-dd_HH-MM-SS');
            formattedDateTime = strrep(formattedDateTime, '-', '_');
            simdir = fullfile(simdir,formattedDateTime);
            mkdir(simdir);           
                        
            if app.StretchmeshCheckBox.Value
                [mesh_file,mesh_path] = uigetfile('*.msh','Select the base msh file');
                if isequal(mesh_path, 0) || isequal(mesh_file, 0)
                    disp('User canceled file selection');
                else
                    mesh_fullpath = fullfile(mesh_path,mesh_file);
                    
                    mesh_stretched_fullpath = fullfile(simdir,'stretched_mesh.msh');
                    stretch_mesh(mesh_fullpath,mesh_stretched_fullpath,app.ScaleXEditField.Value,app.ScaleYEditField.Value,app.ScaleZEditField.Value);                   
                    stretched_mat_path = fullfile(simdir,'stretched_mesh.mat');
                    conv_msh_to_nodes_elements_arrays(mesh_stretched_fullpath, stretched_mat_path)
                    simulation_3d(stretched_mat_path,app.mua1cmEditField.Value,app.mus1cmEditField.Value,app.refEditField.Value);
                end
            else
                [mesh_file,mesh_path] = uigetfile('*.mat','Select the base mat file');
                if isequal(mesh_path, 0) || isequal(mesh_file, 0)
                    disp('User canceled file selection');
                else
                    mesh_fullpath = fullfile(mesh_path,mesh_file);
                    mesh_sim_fullpath = fullfile(simdir, 'ForwardMesh.mat');
                    copyfile(mesh_fullpath,mesh_sim_fullpath)
                    simulation_3d(mesh_sim_fullpath,app.mua1cmEditField.Value,app.mus1cmEditField.Value,app.refEditField.Value);
                end
            end                
        end

        % Value changed function: StretchmeshCheckBox
        function StretchmeshCheckBoxValueChanged(app, event)
            if app.StretchmeshCheckBox.Value
                app.ScaleXEditField.Enable = "on";
                app.ScaleYEditField.Enable = "on";
                app.ScaleZEditField.Enable = "on";
            else
                app.ScaleXEditField.Enable = "off";
                app.ScaleYEditField.Enable = "off";
                app.ScaleZEditField.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DOTSimulatingToolLabel = uilabel(app.UIFigure);
            app.DOTSimulatingToolLabel.Text = 'DOT Simulating Tool';
            app.StretchmeshCheckBox = uicheckbox(app.UIFigure);
            app.StretchmeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @StretchmeshCheckBoxValueChanged, true);
            app.StretchmeshCheckBox.Text = 'Stretch mesh';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.mua1cmEditFieldLabel = uilabel(app.UIFigure);
            app.mua1cmEditFieldLabel.Text = 'mua [1/cm]';
            app.mua1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mua1cmEditField.Limits = [0.03 0.07];
            app.mua1cmEditField.Value = 0.03;
            app.mus1cmEditFieldLabel = uilabel(app.UIFigure);
            app.mus1cmEditFieldLabel.Text = 'mus [1/cm]';
            app.mus1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mus1cmEditField.Limits = [10 20];
            app.mus1cmEditField.Value = 10;
            app.refEditFieldLabel = uilabel(app.UIFigure);
            app.refEditFieldLabel.Text = 'ref';
            app.refEditField = uieditfield(app.UIFigure, 'numeric');
            app.refEditField.Limits = [1.5 1.55];
            app.refEditField.Value = 1.5;
            app.ScaleXEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleXEditFieldLabel.Text = 'Scale X';
            app.ScaleXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleXEditField.Enable = 'off';
            app.ScaleXEditField.Value = 1;
            app.ScaleYEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleYEditFieldLabel.Text = 'Scale Y';
            app.ScaleYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleYEditField.Enable = 'off';
            app.ScaleYEditField.Value = 1;
            app.ScaleZEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleZEditField.Enable = 'off';
            app.ScaleZEditField.Value = 1;
            app.OrenSchorRonMilutinLabel = uilabel(app.UIFigure);
            app.OrenSchorRonMilutinLabel.Text = '© Oren Schor & Ron Milutin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOT_Simulating_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end